classdef AppRecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UiHome               matlab.ui.container.Menu
        UiView               matlab.ui.container.Menu
        Label                matlab.ui.control.Label
        AxesSoundWave        matlab.ui.control.UIAxes
        loadSound            matlab.ui.control.Button
        addSound             matlab.ui.control.Button
        Lamp                 matlab.ui.control.Lamp
        pause                matlab.ui.control.StateButton
        stop                 matlab.ui.control.StateButton
        play                 matlab.ui.control.StateButton
        LabelEditField       matlab.ui.control.Label
        SongName             matlab.ui.control.EditField
        TabGroup             matlab.ui.container.TabGroup
        SettingsTab          matlab.ui.container.Tab
        Pre                  matlab.ui.container.Panel
        LabelSlider          matlab.ui.control.Label
        Sampling             matlab.ui.control.Slider
        SampleRate           matlab.ui.control.NumericEditField
        Label5               matlab.ui.control.Label
        NoiseLevelKnobLabel  matlab.ui.control.Label
        NoiseLevelKnob       matlab.ui.control.Knob
        StatusKnobLabel      matlab.ui.control.Label
        StatusKnob           matlab.ui.control.DiscreteKnob
        Real                 matlab.ui.container.Panel
        LowPass              matlab.ui.control.RockerSwitch
        LabelRockerSwitch    matlab.ui.control.Label
        BandPass             matlab.ui.control.RockerSwitch
        Label3               matlab.ui.control.Label
        HighPass             matlab.ui.control.RockerSwitch
        Label4               matlab.ui.control.Label
        InfoTab              matlab.ui.container.Tab
        UITableInfo          matlab.ui.control.Table
        FftTab               matlab.ui.container.Tab
        WaveletTab           matlab.ui.container.Tab
        SonogramTab          matlab.ui.container.Tab
        AxesSonagram         matlab.ui.control.UIAxes
        TextEditFieldLabel   matlab.ui.control.Label
        TextEditField        matlab.ui.control.EditField
    end


    properties (Access = public)
       
        FileLocation;
        SoundData;
        originalSoundData;%sound data that stays constant afer load
        fs;
        fullpath;
        player;
        Name;
        boolSound;
        numSamples;
        slength;
        filterArray=[0 0 0];
        lowPass;
        bandPass;
        highPass;
        noiseLevel% noiseLevel getting passed to addNoise function
    end

    methods (Access = public)
           
        % Create new figure
        function results = viewstartToplay(app, options)

            app.player.audio = audioplayer(options.audio_signal, options.Fs);                   
            play(app.player.audio);
              
            viewappgui(options);
            results = true;
        end    
        
        % Create new figure
        function results = viewdetails(app)
            
           params= [];
           params.audio_name = app.Name;
           params.slength = app.slength;
           params.options.Fs = app.SampleRate.Value;
           params.numSamples = app.numSamples;
           params.axes.signal      = app.AxesSoundWave;
           params.axes.spectrogram = app.AxesSonagram; 
           params.audio_signal = app.SoundData;
                       
           viewstartup(params);                
           results = true;
        end  
        
    end

    methods (Access = private)

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
                        
        end

        % Callback function
        function BandPassValueChanged(app, event)
            if app.pause.Value==true
                errordlg('Please stop audio before applying filter');
                app.BandPass.Value='Off';
            end
            value = app.BandPass.Value;
            if isequal(value,'On')
                app.filterArray=app.filterArray+[0 1 0];
            elseif isequal(value,'Off')
                app.filterArray=app.filterArray-[0 1 0];
            end
        end

        % Callback function
        function HighPassValueChanged(app, event)
            if app.pause.Value==true
                errordlg('Please stop audio before applying filter');
                app.HighPass.Value='Off';
            end
            value = app.HighPass.Value;
            if isequal(value,'On')
                app.filterArray=app.filterArray+[0 0 1];
            elseif isequal(value,'Off')
                app.filterArray=app.filterArray-[0 0 1];
            end
        end

        % Callback function
        function LowPassValueChanged(app, event)
             if app.pause.Value==true
                errordlg('Please stop audio before applying filter');
                app.LowPass.Value='Off';
            end
            value = app.LowPass.Value;
            if isequal(value,'On')
                app.filterArray=app.filterArray+[1 0 0];
            elseif isequal(value,'Off')
                app.filterArray=app.filterArray-[1 0 0];
            end
        end

        % Callback function
        function NoiseLevelKnobValueChanged(app, event)
            value = app.NoiseLevelKnob.Value;
            
            app.SoundData=addNoise(app.originalSoundData,value);
        end

        % Value changed function: pause
        function PressPause(app, event)
             pausevalue = app.pause.Value;
           if pausevalue == true
               app.play.Value = false; %sets playvalue equal to false
               app.stop.Value =false;  % sets stop value to false
               app.Lamp.Color = 'yellow';%changes lamp color to yellow
               pause(app.player.audio); %pause function in audioplayer object
           else
               app.Lamp.Color = 'green';
               app.play.Value = true;
           end
             
            
        end

        % Value changed function: play
        function PressPlay(app, event)
           playvalue = app.play.Value;
           
           if(app.boolSound) %ensures that a song has actually been loaded in
               if (playvalue == true && app.pause.Value~=true) %checks that pause is not on and that play is on
                   app.pause.Value = false;
                   app.stop.Value =false;
                   app.Lamp.Color = 'green';
                   x=app.filterArray; %sets filterArray to x
                   [app.lowPass,app.bandPass,app.highPass]=effect2(app.SoundData,app.SampleRate.Value);
                   app.slength=app.numSamples/app.SampleRate.Value;
                   
                       appOptions = []; %struct();
                       appOptions.slength = app.slength;
                       appOptions.Fs = app.SampleRate.Value;
                       appOptions.numSamples = app.numSamples;
                       appOptions.axes.signal      = app.AxesSoundWave;
                       appOptions.axes.spectrogram = app.AxesSonagram; 
                       appOptions.audio_signal = [];
                   

                   if isequal(x,[0 0 0]) || isequal(x,[1 1 1])  %checks through all the filters to decide which one to play at
                       appOptions.audio_signal =  app.SoundData;
                       
                   elseif isequal(x,[1 0 0])
                       appOptions.audio_signal =  app.lowPass;
                      
                   elseif isequal(x,[0 1 0])
                       appOptions.audio_signal =  app.bandPass;
                       
                   elseif isequal(x,[0 0 1])
                       appOptions.audio_signal =  app.highPass;

                   elseif isequal(x,[0 1 1])
                       appOptions.audio_signal =  app.bandPass+app.highPass;

                   elseif isequal(x,[1 0 1])
                       appOptions.audio_signal =  app.lowPass+app.highPass;

                   elseif isequal(x,[1 1 0])
                       appOptions.audio_signal =  app.lowPass+app.bandPass;

                   end
                             
                   app.viewstartToplay(appOptions)                 

            
               elseif (playvalue == true && app.pause.Value==true)
                   app.pause.Value = false;
                   app.stop.Value =false;
                   app.Lamp.Color = 'green';
                   resume(app.player.audio);
                   
               else
                   app.Lamp.Color = 'yellow';
                   app.pause.Value = true;
               end
               
           end
             
        end

        % Value changed function: stop
        function PressStop(app, event)
           stopvalue = app.stop.Value;
           if(app.boolSound)
               if stopvalue == true
                   app.play.Value = false;
                   app.pause.Value =false;
                   app.Lamp.Color = 'red';
                   stop(app.player.audio); %function from audioplayer object
               else
                   app.Lamp.Color = 'green';
                   app.play.Value = true;
               end
           end
             
            
        end

        % Callback function
        function SampleValue(app, event)
            value = app.SampleRate.Value;
            if value<=50000 && value >= 1000 %caps off sample frequency between 1000-50000
                app.Sampling.Value = value;
            elseif value<1000
                app.Slider.Value = 1000;
            elseif value>50000
                app.Sampling.Value=5000;
            end
        end

        % Callback function
        function Slider(app, event)
            value = app.Sampling.Value;
            app.SampleRate.Value = value; %ensures that slider is equal to textbox with frequency
        end

        % Button pushed function: addSound
        function addSoundButtonPushed(app, event)
           [app.Name, Location] = uigetfile({'*.wav';'*.mp3';'*.ogg';'*.flac';'*.mp4';'*.m4a';'*.au';'*.aiff';'*.aif';'*.aifc'},'Select Audio File');
           app.UIFigure.Visible = 'off'; %fixes error where fig minimizes Joe Shenouda
           app.UIFigure.Visible = 'on'; %fixes error where fig minmizes Joe Shenouda
           app.SongName.Editable = 'on';
           app.SongName.Value=app.Name;
           app.fullpath=strcat(Location,app.Name);
           
           app.SongName.Editable = 'off';
           app.FileLocation = Location;
          
        end

        % Button pushed function: loadSound
        function loadSoundButtonPushed(app, event)
        if app.SongName.Value==app.Name
            [app.SoundData, app.fs]=audioread(app.fullpath);
            app.numSamples=length(app.SoundData);
            app.originalSoundData=app.SoundData;

            app.boolSound=1;
            app.Sampling.Value=app.fs;
            app.SampleRate.Value=app.Sampling.Value;
            [app.lowPass, app.bandPass, app.highPass]=effect2(app.SoundData,app.SampleRate.Value);
            
            app.viewdetails();
            
        end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UiHome = uimenu(app.UIFigure);
            app.UiHome.Text = 'Home';
            app.UiView = uimenu(app.UIFigure);
            app.UiView.Text = 'View';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ICS, Spech Analys v3.0.1';
            app.AxesSoundWave = uiaxes(app.UIFigure);
            app.loadSound = uibutton(app.UIFigure, 'push');
            app.loadSound.ButtonPushedFcn = createCallbackFcn(app, @loadSoundButtonPushed, true);
            app.loadSound.Text = 'Load Sound';
            app.addSound = uibutton(app.UIFigure, 'push');
            app.addSound.ButtonPushedFcn = createCallbackFcn(app, @addSoundButtonPushed, true);
            app.addSound.Text = 'Add New Sound';
            app.Lamp = uilamp(app.UIFigure);
            app.pause = uibutton(app.UIFigure, 'state');
            app.pause.ValueChangedFcn = createCallbackFcn(app, @PressPause, true);
            app.pause.Text = 'Pause';
            app.stop = uibutton(app.UIFigure, 'state');
            app.stop.ValueChangedFcn = createCallbackFcn(app, @PressStop, true);
            app.stop.Text = 'Stop';
            app.play = uibutton(app.UIFigure, 'state');
            app.play.ValueChangedFcn = createCallbackFcn(app, @PressPlay, true);
            app.play.Text = 'Play';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Select A Sound';
            app.SongName = uieditfield(app.UIFigure, 'text');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.Pre = uipanel(app.SettingsTab);
            app.Pre.Title = 'Pre-Processing Effects';
            app.LabelSlider = uilabel(app.Pre);
            app.LabelSlider.Text = 'Sampling  Rate';
            app.Sampling = uislider(app.Pre);
            app.Sampling.Limits = [1000 50000];
            app.Sampling.Value = 1000;
            app.SampleRate = uieditfield(app.Pre, 'numeric');
            app.SampleRate.Limits = [1000 50000];
            app.SampleRate.ValueDisplayFormat = '%5.2f';
            app.SampleRate.Value = 1000;
            app.Label5 = uilabel(app.Pre);
            app.Label5.Text = 'Hz';
            app.NoiseLevelKnobLabel = uilabel(app.Pre);
            app.NoiseLevelKnobLabel.Text = 'Noise Level';
            app.NoiseLevelKnob = uiknob(app.Pre, 'continuous');
            app.NoiseLevelKnob.Limits = [0 50];
            app.StatusKnobLabel = uilabel(app.Pre);
            app.StatusKnobLabel.Text = 'Status';
            app.StatusKnob = uiknob(app.Pre, 'discrete');
            app.Real = uipanel(app.SettingsTab);
            app.Real.Title = 'Real-Time Effects';
            app.LowPass = uiswitch(app.Real, 'rocker');
            app.LabelRockerSwitch = uilabel(app.Real);
            app.LabelRockerSwitch.Text = 'Bass';
            app.BandPass = uiswitch(app.Real, 'rocker');
            app.Label3 = uilabel(app.Real);
            app.Label3.Text = 'Mid-Range';
            app.HighPass = uiswitch(app.Real, 'rocker');
            app.Label4 = uilabel(app.Real);
            app.Label4.Text = 'Tremble';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.UITableInfo = uitable(app.InfoTab);
            app.UITableInfo.ColumnName = {'Name'; 'Value'};
            app.UITableInfo.RowName = {};
            app.FftTab = uitab(app.TabGroup);
            app.FftTab.Title = 'Fft';
            app.WaveletTab = uitab(app.TabGroup);
            app.WaveletTab.Title = 'Wavelet';
            app.SonogramTab = uitab(app.TabGroup);
            app.SonogramTab.Title = 'Sonogram';
            app.AxesSonagram = uiaxes(app.SonogramTab);
            app.TextEditFieldLabel = uilabel(app.UIFigure);
            app.TextEditFieldLabel.Text = 'Text';
            app.TextEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = AppRecognition

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end